classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        JacoStatusTab               matlab.ui.container.Tab
        GREENrunningREDstoppedLampLabel  matlab.ui.control.Label
        GREENrunningREDstoppedLamp  matlab.ui.control.Lamp
        SwitchLabel                 matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
        EMERGENCYSTOPButton         matlab.ui.control.Button
        RobotConfigurationTab       matlab.ui.container.Tab
        q1SliderLabel               matlab.ui.control.Label
        q1Slider                    matlab.ui.control.Slider
        q2SliderLabel               matlab.ui.control.Label
        q2Slider                    matlab.ui.control.Slider
        q3SliderLabel               matlab.ui.control.Label
        q3Slider                    matlab.ui.control.Slider
        q4SliderLabel               matlab.ui.control.Label
        q4Slider                    matlab.ui.control.Slider
        q5SliderLabel               matlab.ui.control.Label
        q5Slider                    matlab.ui.control.Slider
        q6SliderLabel               matlab.ui.control.Label
        q6Slider                    matlab.ui.control.Slider
        JointAnglesLabel            matlab.ui.control.Label
        xEditFieldLabel             matlab.ui.control.Label
        xEditField                  matlab.ui.control.NumericEditField
        yEditFieldLabel             matlab.ui.control.Label
        yEditField                  matlab.ui.control.NumericEditField
        zEditFieldLabel             matlab.ui.control.Label
        zEditField                  matlab.ui.control.NumericEditField
        CartesianMovementLabel      matlab.ui.control.Label
        SensorDataTab               matlab.ui.container.Tab
    end


    properties (Access = private)
         robot; % Jaco robot links and properties
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robot = Jaco();
            app.robot.GetJacoRobot();
            jacoBase = transl(1.5, 1,0.25)*trotz(pi);
            app.robot.model.base = jacoBase;
            
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 1;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
           
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 2;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 3;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changing function: q4Slider
        function q4SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 4;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changing function: q5Slider
        function q5SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 5;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changing function: q6Slider
        function q6SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 6;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            cartesian = 'x';
            variable = value;
            UpdateCartesian(app.robot, cartesian, variable);
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            cartesian = 'y';
            variable = value;
            UpdateCartesian(app.robot, cartesian, variable);
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            value = app.zEditField.Value;
            cartesian = 'z';
            variable = value;
            UpdateCartesian(app.robot, cartesian, variable);
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            eStopButton = 1;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.JacoStatusTab = uitab(app.TabGroup);
            app.JacoStatusTab.Title = 'Jaco Status';
            app.GREENrunningREDstoppedLampLabel = uilabel(app.JacoStatusTab);
            app.GREENrunningREDstoppedLampLabel.Text = {'GREEN = running'; 'RED = stopped'};
            app.GREENrunningREDstoppedLamp = uilamp(app.JacoStatusTab);
            app.SwitchLabel = uilabel(app.JacoStatusTab);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.JacoStatusTab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.EMERGENCYSTOPButton = uibutton(app.JacoStatusTab, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = {'EMERGENCY '; 'STOP'};
            app.RobotConfigurationTab = uitab(app.TabGroup);
            app.RobotConfigurationTab.Title = 'Robot Configuration';
            app.q1SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.RobotConfigurationTab);
            app.q1Slider.Limits = [-10 10];
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.q2SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.RobotConfigurationTab);
            app.q2Slider.Limits = [47 313];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q2Slider.Value = 47;
            app.q3SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.RobotConfigurationTab);
            app.q3Slider.Limits = [19 341];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.q3Slider.Value = 19;
            app.q4SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.RobotConfigurationTab);
            app.q4Slider.Limits = [-10 10];
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @q4SliderValueChanging, true);
            app.q5SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.RobotConfigurationTab);
            app.q5Slider.Limits = [65 295];
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @q5SliderValueChanging, true);
            app.q5Slider.Value = 65;
            app.q6SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.RobotConfigurationTab);
            app.q6Slider.Limits = [-10 10];
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @q6SliderValueChanging, true);
            app.JointAnglesLabel = uilabel(app.RobotConfigurationTab);
            app.JointAnglesLabel.Text = 'Joint Angles';
            app.xEditFieldLabel = uilabel(app.RobotConfigurationTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.RobotConfigurationTab, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.RobotConfigurationTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.RobotConfigurationTab, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.zEditFieldLabel = uilabel(app.RobotConfigurationTab);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.RobotConfigurationTab, 'numeric');
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.CartesianMovementLabel = uilabel(app.RobotConfigurationTab);
            app.CartesianMovementLabel.Text = 'Cartesian Movement';
            app.SensorDataTab = uitab(app.TabGroup);
            app.SensorDataTab.Title = 'Sensor Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end